classdef Copy_of_app_Heatmap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button_2       matlab.ui.control.Button
        Button         matlab.ui.control.Button
        Image          matlab.ui.control.Image
        ClearButton    matlab.ui.control.Button
        AddDataButton  matlab.ui.control.Button
    end


    properties (Access = private)
        Sayac
        Max
        j
        tempp_app
        fileName_app
        
    end
    
    methods (Access = private, Static)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddDataButton
        function AddDataButtonPushed(app, event)
           
            
            [fileName,folder] = uigetfile('*.mat',"RAR dosyasını seçin",'MultiSelect','on');
            fileNum=dir([folder '*.mat']);
            [num,temp]=size(fileNum);
            [tempp,numberOfFile]=size(fileName);


            if(class(fileName) == 'char')
                numberOfFile=1;
                fileName = cellstr(fileName);
            end

            for i=1:numberOfFile
                fullFileName = fullfile(folder,fileName{tempp,i});
                for a=1:num
                    if(a<=num/3)
                        if(a<10)
                            ad=sprintf('RAR0%d_RangeAngleResponse.mat',a);
                            digerad=sprintf('RG0%d_RangeAngleResponse.mat',a);
                            digerad2=sprintf('AG0%d_RangeAngleResponse.mat',a);

                        else
                            ad=sprintf('RAR%d_RangeAngleResponse.mat',a);
                            digerad=sprintf('RG%d_RangeAngleResponse.mat',a);
                            digerad2=sprintf('AG%d_RangeAngleResponse.mat',a);
                        end
                    else
                        break;
                    end
                    
                    if(string(fileName{tempp,i})==ad)
                        G(i)=load(fullFileName);
                        fileName{tempp,i} = digerad2;
                        fullFileName=fullfile(folder,fileName{tempp,i});
                        F(i)=load(fullFileName);
                        fileName{tempp,i} = digerad;
                        fullFileName=fullfile(folder,fileName{tempp,i});
                        H(i)=load(fullFileName);

                    elseif(string(fileName{tempp,i})==digerad)
                        H(i)=load(fullFileName);
                        fileName{tempp,i} = ad;
                        fullFileName=fullfile(folder,fileName{tempp,i});
                        G(i)=load(fullFileName);
                        fileName{tempp,i} = digerad2;
                        fullFileName=fullfile(folder,fileName{tempp,i});
                        F(i)=load(fullFileName);

                    elseif(string(fileName{tempp,i})==digerad2)
                        F(i)=load(fullFileName);
                        fileName{tempp,i} = ad;
                        fullFileName=fullfile(folder,fileName{tempp,i});
                        G(i)=load(fullFileName);
                        fileName{tempp,i} = digerad;
                        fullFileName=fullfile(folder,fileName{tempp,i});
                        H(i)=load(fullFileName);

                    else

                        continue;
                    end
                    [row,col,frame] = size(G(i).RAR_RangeAngleResponse);

                    flag=1;
                    total=0;
                    while(flag<=frame)
                        total = total + G(i).RAR_RangeAngleResponse(:,:,flag);
                        flag = flag+1;     
                    end
                    average_data(:,:,i) = (total/frame);
                end
               

            end


            for i=1:numberOfFile
                
                h(i) = figure();
                ax = nexttile();
                i;
                
                [row_AG,col_AG] = size(F(i).AG_RangeAngleResponse);
                [row_RG,col_RG] = size(H(i).RG_RangeAngleResponse);
                
                
                x3d = F(i).AG_RangeAngleResponse(1:64);
                x3d(1) = -76;
                x3d = transpose(x3d);
                y3d = H(i).RG_RangeAngleResponse(1:256);
                y3d = transpose(y3d);
                RAR_Average = average_data(:,:,i);

                ZZ = flip(squeeze(RAR_Average(flip(1:256), 1: 64)));
                polarPcolor(y3d,x3d,mag2db(ZZ) , 'Ncircles',5, 'Nspokes',5,'colBar',0,'labelR','Range [m]');
            
                disp("Maxmin");
                Max = max(mag2db(ZZ),[],"all")
                disp("Maxmin");
                Min = min(mag2db(ZZ),[],"all")
                
                cb = colorbar;
                cb.Label.String = 'Power(dB)' ;
                colormap('jet');
                caxis([-30 60]);          
                ax.Visible="on";
                ax.XAxis.Visible='on';
                ax.YAxis.Visible='on';
            
                str_ = 'Range−Angle Graph   ';
                str_num = string(fileName{tempp,i});
                str_num = char(str_num);
                str_1 = str_num(strlength(str_num)-24);
                str_2 = str_num(strlength(str_num)-23);
                str_3 = append(str_1,str_2);
                str_title = append(str_,str_3);
  

                title(ax,{str_title;''}, '.' , 'FontSize' , 10);

                ax.Visible="on";
                xlabel(ax,"lateral axis (meters)");
                ylabel(ax,"longitudinal axis (meters)");
                xlim(ax,[H(i).RG_RangeAngleResponse(256)*sin(F(i).AG_RangeAngleResponse(2)*pi/180) H(i).RG_RangeAngleResponse(256)*sin(F(i).AG_RangeAngleResponse(64)*pi/180)]);
                ylim(ax,[0 H(i).RG_RangeAngleResponse(256)]);
                grid (ax,'on');
                grid minor;

                str1 = str_3;
                str2 = 'Figures.png';
                str3 = append(str1,str2);
                
                saveas(h(i),str3);
                app.Image.ImageSource = str3; %'1Figures.jpg';
                
            end

            app.tempp_app = tempp;
            app.fileName_app = fileName;
            
            
            app.Max = numberOfFile;
            app.Sayac = numberOfFile;
            app.j = numberOfFile;
                       
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.Image.ImageSource = 'clear4.jpg';
            app.Sayac = 0;
            delete('/MATLAB Drive/bitirme/GUI/*.png');
            close all;
        end

        % Button pushed function: Button_2
        function Button_2Push(app, event)
            

            if (app.j+1) <= app.Max
                
                app.j = app.j+1;

                str_num = string(app.fileName_app{app.tempp_app,app.j});
                str_num = char(str_num);
                str_1 = str_num(strlength(str_num)-24);
                str_2 = str_num(strlength(str_num)-23);
                str_3 = append(str_1,str_2);
                str1 = str_3;
                str2 = 'Figures.png';
                str3 = append(str1,str2);
                app.Image.ImageSource = str3;
            end


        end

        % Button pushed function: Button
        function ButtonPush(app, event)
            
            if (app.j-1) >= 1
                
                app.j = app.j-1;

                str_num = string(app.fileName_app{app.tempp_app,app.j});
                str_num = char(str_num);
                str_1 = str_num(strlength(str_num)-24);
                str_2 = str_num(strlength(str_num)-23);
                str_3 = append(str_1,str_2);
                str1 = str_3;
                str2 = 'Figures.png';
                str3 = append(str1,str2);
                app.Image.ImageSource = str3;
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddDataButton = uibutton(app.UIFigure, 'push');
            app.AddDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddDataButtonPushed, true);
            app.AddDataButton.Text = 'Add Data';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Image = uiimage(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPush, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'arrow-24819.svg');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Push, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'right-24825.svg');
            app.Button_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_app_Heatmap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end