classdef Copy_of_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        YenileButton    matlab.ui.control.Button
        VeriEkleButton  matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Sayac=0 
        leg
        legText 
    end
    
    methods (Access = private, Static)
        
        function actionn(app,event)
            if strcmp(event.Peer.Visible,'on')   % If current line is visible
                event.Peer.Visible = 'off';      % Set the visibility to 'off'
            else                                 % Else
                event.Peer.Visible = 'on';       % Set the visibility to 'on'
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VeriEkleButton
        function VeriEkleButtonPushed(app, event)
            [fileName,folder] = uigetfile('*.mat',"RG dosyasını seçin",'MultiSelect','on');
            fileNum=dir([folder '*.mat']);
            [num,temp]=size(fileNum);
            [tempp,numberOfFile]=size(fileName);
            disp('sayac');
            disp(app.Sayac);
            if(class(fileName) == 'char')
                numberOfFile=1;
                fileName = cellstr(fileName);
            end

            for i=1:numberOfFile
                fullFileName = fullfile(folder,fileName{tempp,i});
                for a=1:num
                    if(a<=num/2)
                        if(a<10)
                            ad=sprintf('RG0%d_RangeProfile.mat',a);
                            digerad=sprintf('RP0%d_RangeProfile.mat',a);
                        else
                            ad=sprintf('RG%d_RangeProfile.mat',a);
                            digerad=sprintf('RP%d_RangeProfile.mat',a);
                        end
                    else
                        break;
                    end
                    
                    if(fileName{tempp,i}==ad)
                        X(i)=load(fullFileName);
                        fileName{tempp,i} = digerad;
                        fullFileName=fullfile(folder,fileName{tempp,i});
                        Y(i)=load(fullFileName);
                    elseif(string(fileName{tempp,i})==digerad)
                        Y(i)=load(fullFileName);
                        fileName{tempp,i} = ad;
                        fullFileName=fullfile(folder,fileName{tempp,i});
                        X(i)=load(fullFileName);
                    else

                        continue;
                    end
                    [row,col] = size(Y(i).RP_RangeProfile);

                    flag=1;
                    total=0;
                    while(flag<=row)
                        total = total + Y(i).RP_RangeProfile(flag,:);
                        flag = flag+1;     
                    end
                    average_data(i,:) = (total/row);
                end
            end
            
            
            nPoints = 30;
            xLimits = [0.3,1.2];
            yLimits = [0,150];
            ylim(app.UIAxes,yLimits);
            xlim(app.UIAxes,xLimits);
            xticks(app.UIAxes,linspace(xLimits(1),xLimits(2),nPoints));
            yticks(app.UIAxes,linspace(yLimits(1),yLimits(2),nPoints));

            for i=1:numberOfFile
                plot(app.UIAxes,X(i).RG_RangeProfile,[average_data(i,:)],'LineWidth',1);
                hold(app.UIAxes,'on');
                grid(app.UIAxes,'on');
                
                app.Sayac=app.Sayac+1;
                if (app.Sayac==1)
                    app.legText = sprintf("%d.veri",app.Sayac);
                end
                if (app.Sayac~=1)
                    legText_add = sprintf("%d.veri",app.Sayac);
                    app.legText(end+1)=legText_add
                end
                hLeg = legend(app.UIAxes,app.legText);
                
            end
            hLeg.ItemHitFcn = @app.actionn;
            xlabel(app.UIAxes,"Range (m)");
            ylabel(app.UIAxes,"Relative Power (dB)");
            title(app.UIAxes,"Range Profile");

            
            disp(app.Sayac);
                       
        end

        % Button pushed function: YenileButton
        function YenileButtonPushed(app, event)
            cla(app.UIAxes);
            app.Sayac=0;
            disp(app.Sayac);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.VeriEkleButton = uibutton(app.UIFigure, 'push');
            app.VeriEkleButton.ButtonPushedFcn = createCallbackFcn(app, @VeriEkleButtonPushed, true);
            app.VeriEkleButton.Text = 'Veri Ekle';
            app.YenileButton = uibutton(app.UIFigure, 'push');
            app.YenileButton.ButtonPushedFcn = createCallbackFcn(app, @YenileButtonPushed, true);
            app.YenileButton.Text = 'Yenile';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end